classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Figure                   matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        LeftPanel                matlab.ui.container.Panel
        TabGroup                 matlab.ui.container.TabGroup
        AxisView                 matlab.ui.container.Tab
        cart_lidar               matlab.ui.control.UIAxes
        polar_lidar              matlab.ui.control.UIAxes
        CalculationTimingTab     matlab.ui.container.Tab
        timing                   matlab.ui.control.UIAxes
        Tab                      matlab.ui.container.Tab
        GCF                      matlab.ui.control.UIAxes
        RightPanel               matlab.ui.container.Panel
        PauseButton              matlab.ui.control.StateButton
        ResetPlaybackButton      matlab.ui.control.Button
        PlaybackStatusLabel      matlab.ui.control.Label
        EXITButton               matlab.ui.control.Button
        PlaybackSpeedLabel       matlab.ui.control.Label
        SlowDownButton           matlab.ui.control.Button
        SpeedUpButton            matlab.ui.control.Button
        PausedLabel              matlab.ui.control.Label
        PlayButton               matlab.ui.control.StateButton
        ActiveFileDropDown       matlab.ui.control.DropDown
        ActiveFileDropDownLabel  matlab.ui.control.Label
        TabGroup2                matlab.ui.container.TabGroup
        ErrorTab                 matlab.ui.container.Tab
        Error                    matlab.ui.control.UIAxes
        VisibilityControlTab     matlab.ui.container.Tab
        visibilityControl        matlab.ui.container.Panel
        GroundTruthSwitch        matlab.ui.control.Switch
        GroundTruthSwitchLabel   matlab.ui.control.Label
        GTOOISwitch              matlab.ui.control.Switch
        GTOOISwitchLabel         matlab.ui.control.Label
        DREstimateSwitch         matlab.ui.control.Switch
        DREstimateSwitchLabel    matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    properties (Access = private)
        gtData(3, :) double
        errData (4, :) double
        type_idx (1, :) double
        timingData (1, :) double
        idx double = 1
        arrow function_handle
        gt_idx double = 1
        l_idx double = 1

        playbackLoop logical = false
        speed double = 1
    end
    
    properties (Access = public)
        gcfP struct
        ldrP struct
        % InitPlot struct
        errP struct
        file string
        timeP
    end

    methods (Access = public)
        
        % initilising plot 
        function init(app, arrow)
            arguments
                app;
                arrow function_handle
            end
            app.arrow = arrow;
            set(app.PauseButton, 'Visible', false)
            set(app.ResetPlaybackButton, 'Visible', false);

            gcf = app.GCF;
            app.gcfP = struct( ...
                "deadReckon", line(gcf,[0 0], [0 0], 'Color', 'b', "LineStyle", "none", "Marker", ".", "MarkerSize", 2), ...
                "driverArrow", line(gcf,[0 0], [0 0],'Color', 'r', 'LineStyle', 'none', 'Marker', "*", "MarkerSize", 3), ...
                "groundTruth", line(gcf, [0 0], [0 0], 'Color', 'g', "LineStyle", "none", "Marker", ".", "MarkerSize", 1), ...
                "ooi", line(gcf, [0 0], [0 0], 'Color','m', "LineStyle", "none", "Marker", "^"), ...
                "walls", line(gcf, [0 0], [0 0], 'LineWidth', 2, 'Color', [205, 127, 50]/255), ...,
                "lidar_points", line(gcf, [0 0], [0 0], "LineStyle", "none", "Marker", ".", 'Color', "#800000"), ...,
                "dooi", line(gcf, [0 0], [0 0], 'Color','#FFA500', "LineStyle", "none", "Marker", "diamond") ...
            );

            legend(gcf, ...
            [app.gcfP.deadReckon, app.gcfP.driverArrow, app.gcfP.groundTruth, app.gcfP.ooi, app.gcfP.walls], ... 
            {"Estimated Position", "Current Location", "Ground Truth (GT)", "GT OOI", "Walls"}, ...
            'Location', 'northeast', ...
             'Orientation', 'vertical');

            err = app.Error;
            app.errP = struct(...
                'x', line(err, [0 0], [0 0], 'Color', 'r', 'LineWidth', 1.5),...
                'y', line(err, [0 0], [0 0], 'Color', 'b', 'LineWidth', 1.5),...
                'h', line(err, [0 0], [0 0], 'Color', 'y', 'LineWidth', 1.5),...
                'total', line(err, [0 0], [0 0], 'Color', 'g', 'LineWidth', 1.5)...
                );


            legend(err, [app.errP.x, app.errP.y, app.errP.h, app.errP.total],...
                {"X Axis", "Y Axis", "Heading Error", "Total Error"},...
                'Location', 'northeast', ...
                'Orientation', 'vertical')

            t = app.timing;
            app.timeP = line(t, [1 1], [0 0], 'Color', 'r', "LineStyle", "none", "Marker", "*", "MarkerSize", 3);

            ldr_p = app.polar_lidar;
            ldr_c = app.cart_lidar;

            
            app.ldrP = struct(...
                'polar', line(ldr_p, [0 0], [0 0], 'LineStyle', 'none', 'Marker', '.', 'Color', 'b'), ...
                'cart', line(ldr_c, [0 0], [0 0], 'LineStyle', 'none', 'Marker', '.', 'Color', 'b'), ...
                'ooiP', line(ldr_p, [0 0], [0 0], 'LineStyle', 'none', 'Marker', 'o', 'Color', 'm'), ...
                'ooiC', line(ldr_c, [0 0], [0 0], 'LineStyle', 'none', 'Marker', 'o', 'Color', 'm')...
            );

            legend(ldr_p, [app.ldrP.polar, app.ldrP.ooiP],...
                {"Points", "OOI Found"},...
                'Location', 'eastoutside', ...
                'Orientation', 'vertical')

            legend(ldr_c, [app.ldrP.cart, app.ldrP.ooiC],...
                {"Points", "OOI Found"},...
                'Location', 'eastoutside', ...
                'Orientation', 'vertical')

            
            directory = dir('datasets/*.mat');
            fileList = {};
            for i = 1:length(directory)
                fileList{i} = directory(i).name;
            end

            app.ActiveFileDropDown.Items = fileList;
            app.ActiveFileDropDown.ItemsData = fileList;

            app.file = app.ActiveFileDropDown.Value;

        end

        function fileName = getFileName(app)
            fileName = app.file;
        end

        function err = setFileName(app, fileName)
            if ismember(app.ActiveFileDropDown.Items, fileName)
                err = 0;
                app.ActiveFileDropDown.Value = fileName;
                app.file = fileName;
            else
                err = 1;
            end
        end
        
        function load(app, info, gt)
            Context = info.Context;
            set(app.gcfP.walls, "XData", Context.Walls(1,:), "YData", Context.Walls(2,:));
            set(app.gcfP.ooi, "XData", Context.Landmarks(1,:), "YData", Context.Landmarks(2,:));
            app.gtData = gt;
            set(app.gcfP.groundTruth, "XData", app.gtData(1,:), "YData", app.gtData(2,:));
        end

        function updateGCFPlot(app, X, drData, idx)
            dr = app.gcfP.deadReckon;
            set(dr, "XData", drData(1,1:idx), "YData", drData(2, 1:idx));
            
            set(app.gcfP.driverArrow, "XData", X(1), "YData", X(2));


        end
        
        function updateErrPlot(app, eData, idx)
            x = app.errP.x;
            set(x, "XData", eData(1, 1:idx), "YData", 1:idx);
            y = app.errP.y;
            set(y, "XData", eData(2,1:idx), "YData", 1:idx);
            h = app.errP.h;
            set(h, "XData", eData(3,1:idx), "YData", 1:idx);
            t = app.errP.total;
            set(t, "XData", eData(4,1:idx), "YData", 1:idx);
        end

        function updateTimingPlot(app, tData, idx)
            tP = app.timeP;
            set(tP, "XData", 1:idx,"YData", tData(1,1:idx));
        end

        function updateVisibility(app)
            gt = app.gcfP.groundTruth;
            set(gt, 'Visible', app.GroundTruthSwitch.Value);
            o = app.gcfP.ooi;
            set(o, 'Visible', app.GTOOISwitch.Value);
            d = app.gcfP.deadReckon;
            set(d, 'Visible', app.DREstimateSwitch.Value);
        end

        function updatePolarPlot(app, points, ooi)
            p = app.ldrP.polar;
            set(p, "XData", -75:0.5:75, "YData", points(1,:));
            y = app.ldrP.ooiP;
            set(y, "XData", ooi(1,:), "YData", ooi(2,:));
        end

        function updateCartLPlot(app, points, ooi)
            p = app.ldrP.cart;
            set(p, "XData", points(1,:), "YData", points(2,:));
            y = app.ldrP.ooiC;
            set(y, "XData", ooi(1,:), "YData", ooi(2,:));
        end

        function updateGCFLiDARPlot(app, points, ooi)
            p = app.gcfP.lidar_points;
            set(p, "XData", points(1,:), "YData", points(2,:));
            
            p = app.gcfP.dooi;
            set(p, "XData", ooi(1,:), "YData", ooi(2,:));
        end
        
        function updateData(app, drData, errData, typeIDX, timing)
            app.drData = drData;
            app.errData = errData;
            app.type_idx = typeIDX;
            app.timing = timing;
        end

        % function loop(app)
        %     app.playbackLoop = true;
        %     while(app.playbackLoop)
        % 
        % 
        % 
        %         pause(0.025*app.speed);
        %     end
        % end
    end

    events
        FileChange
        Exit
        SpeedUp
        SlowDown
        ResetPlayback
        Play
        Pause
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ActiveFileDropDown
        function ActiveFileDropDownValueChanged(app, event)
            value = app.ActiveFileDropDown.Value;
            app.file = value;
            notify(app, "FileChange")
        end

        % Value changed function: DREstimateSwitch, GTOOISwitch, 
        % ...and 1 other component
        function PositionIndicatorSwitchValueChanged(app, event)
            app.updateVisibility()
        end

        % Button pushed function: EXITButton
        function EXITButtonPushed(app, event)
            notify(app, "Exit")
        end

        % Button pushed function: SlowDownButton
        function SlowDownButtonPushed(app, event)
            % app.speed = app.speed - 0.05;
            notify(app,"SlowDown");
        end

        % Button pushed function: SpeedUpButton
        function SpeedUpButtonPushed(app, event)
            app.speed = app.speed + 0.05;
            notify(app, "SpeedUp");
        end

        % Value changed function: PlayButton
        function PlayButtonValueChanged(app, event)
            app.PausedLabel.Text = "Playing";
            set(app.PlayButton, 'Visible', false);
            set(app.PauseButton, 'Visible', true)
            notify(app, "Play");
        end

        % Button pushed function: ResetPlaybackButton
        function ResetPlaybackButtonPushed(app, event)
            notify(app, "ResetPlayback");
        end

        % Value changed function: PauseButton
        function PauseButtonValueChanged(app, event)
            app.PausedLabel.Text = "Paused";
            set(app.PauseButton, 'Visible', false)
            set(app.PlayButton, 'Visible', true)
            notify(app, "Pause");
        end

        % Callback function
        function PauseButtonValueChanged2(app, event)
           
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.Figure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {882, 882};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {703, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Figure = uifigure('Visible', 'off');
            app.Figure.Name = 'Project 1 Dashboard';
            app.Figure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.Figure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GCF = uiaxes(app.LeftPanel);
            title(app.GCF, 'GCF Map')
            xlabel(app.GCF, 'X')
            ylabel(app.GCF, 'Y')
            app.GCF.XLim = [-5 20];
            app.GCF.YLim = [-5 20];
            app.GCF.XTick = [-5 0 5 10 15 20];
            app.GCF.XTickLabel = {'-5'; '0'; '5'; '10'; '15'; '20'};
            app.GCF.YTick = [-5 0 5 10 15 20];
            app.GCF.YTickLabel = {'-5'; '0'; '5'; '10'; '15'; '20'};
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.AxisView = uitab(app.TabGroup);
            app.AxisView.Title = 'LiDAR';
            app.polar_lidar = uiaxes(app.AxisView);
            title(app.polar_lidar, 'LIDAR_1 in Polar')
            xlabel(app.polar_lidar, 'X')
            ylabel(app.polar_lidar, 'Y')
            app.cart_lidar = uiaxes(app.AxisView);
            title(app.cart_lidar, 'LIDAR_1 in Cartesian')
            xlabel(app.cart_lidar, 'X')
            ylabel(app.cart_lidar, 'Y')
            zlabel(app.cart_lidar, 'Z')
            app.cart_lidar.XLim = [-10 20];
            app.cart_lidar.YLim = [-15 15];
            app.CalculationTimingTab = uitab(app.TabGroup);
            app.CalculationTimingTab.Title = 'Calculation Timing';
            app.timing = uiaxes(app.CalculationTimingTab);
            title(app.timing, 'Calculating Timing')
            xlabel(app.timing, 'Events')
            ylabel(app.timing, 'Time (s)')
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup2 = uitabgroup(app.RightPanel);
            app.ErrorTab = uitab(app.TabGroup2);
            app.ErrorTab.Title = 'Error';
            app.Error = uiaxes(app.ErrorTab);
            title(app.Error, 'Measured Error')
            app.Error.XLimitMethod = 'padded';
            app.Error.YLimitMethod = 'padded';
            app.Error.ZLimitMethod = 'padded';
            app.Error.YDir = 'reverse';
            app.Error.YAxisLocation = 'origin';
            app.Error.YTickLabel = '';
            app.VisibilityControlTab = uitab(app.TabGroup2);
            app.VisibilityControlTab.Title = 'Visibility Control';
            app.visibilityControl = uipanel(app.VisibilityControlTab);
            app.visibilityControl.Title = 'Visibility Control';
            app.DREstimateSwitchLabel = uilabel(app.visibilityControl);
            app.DREstimateSwitchLabel.Text = 'DR Estimate';
            app.DREstimateSwitch = uiswitch(app.visibilityControl, 'slider');
            app.DREstimateSwitch.ItemsData = {'off', 'on'};
            app.DREstimateSwitch.ValueChangedFcn = createCallbackFcn(app, @PositionIndicatorSwitchValueChanged, true);
            app.DREstimateSwitch.Value = 'on';
            app.GTOOISwitchLabel = uilabel(app.visibilityControl);
            app.GTOOISwitchLabel.Text = 'GT OOI';
            app.GTOOISwitch = uiswitch(app.visibilityControl, 'slider');
            app.GTOOISwitch.ItemsData = {'off', 'on'};
            app.GTOOISwitch.ValueChangedFcn = createCallbackFcn(app, @PositionIndicatorSwitchValueChanged, true);
            app.GTOOISwitch.Value = 'on';
            app.GroundTruthSwitchLabel = uilabel(app.visibilityControl);
            app.GroundTruthSwitchLabel.Text = 'Ground Truth';
            app.GroundTruthSwitch = uiswitch(app.visibilityControl, 'slider');
            app.GroundTruthSwitch.ItemsData = {'off', 'on'};
            app.GroundTruthSwitch.ValueChangedFcn = createCallbackFcn(app, @PositionIndicatorSwitchValueChanged, true);
            app.GroundTruthSwitch.Value = 'on';
            app.ActiveFileDropDownLabel = uilabel(app.RightPanel);
            app.ActiveFileDropDownLabel.Text = 'Active File';
            app.ActiveFileDropDown = uidropdown(app.RightPanel);
            app.ActiveFileDropDown.ValueChangedFcn = createCallbackFcn(app, @ActiveFileDropDownValueChanged, true);
            app.PlayButton = uibutton(app.RightPanel, 'state');
            app.PlayButton.ValueChangedFcn = createCallbackFcn(app, @PlayButtonValueChanged, true);
            app.PlayButton.Text = '⏯️';
            app.PlayButton.Value = true;
            app.PausedLabel = uilabel(app.RightPanel);
            app.PausedLabel.Text = 'Paused';
            app.SpeedUpButton = uibutton(app.RightPanel, 'push');
            app.SpeedUpButton.ButtonPushedFcn = createCallbackFcn(app, @SpeedUpButtonPushed, true);
            app.SpeedUpButton.Text = 'Speed Up';
            app.SlowDownButton = uibutton(app.RightPanel, 'push');
            app.SlowDownButton.ButtonPushedFcn = createCallbackFcn(app, @SlowDownButtonPushed, true);
            app.SlowDownButton.Text = 'Slow Down';
            app.PlaybackSpeedLabel = uilabel(app.RightPanel);
            app.PlaybackSpeedLabel.Text = 'Playback Speed:';
            app.EXITButton = uibutton(app.RightPanel, 'push');
            app.EXITButton.ButtonPushedFcn = createCallbackFcn(app, @EXITButtonPushed, true);
            app.EXITButton.Text = 'EXIT';
            app.PlaybackStatusLabel = uilabel(app.RightPanel);
            app.PlaybackStatusLabel.Text = 'Playback Status: ';
            app.ResetPlaybackButton = uibutton(app.RightPanel, 'push');
            app.ResetPlaybackButton.ButtonPushedFcn = createCallbackFcn(app, @ResetPlaybackButtonPushed, true);
            app.ResetPlaybackButton.Text = 'Reset Playback';
            app.PauseButton = uibutton(app.RightPanel, 'state');
            app.PauseButton.ValueChangedFcn = createCallbackFcn(app, @PauseButtonValueChanged, true);
            app.PauseButton.Text = '⏯️';
            app.PauseButton.Value = true;
            % Show the figure after all components are created
            app.Figure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Figure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Figure)
        end
    end
end